classdef tutorialApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        InsertWatermarkPanel            matlab.ui.container.Panel
        InsertWatermarkInallfilesButton  matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        NumberofWatermarksSpinnerLabel  matlab.ui.control.Label
        NumberofWatermarksSpinner       matlab.ui.control.Spinner
        ExtractWatermarkPanel           matlab.ui.container.Panel
        PersonWhoLeakedLabel            matlab.ui.control.Label
        SearchWaterMarkButton           matlab.ui.control.Button
        FileNameLabel                   matlab.ui.control.Label
        FileNameEditField               matlab.ui.control.EditField
    end

    methods (Access = private)

        % Button pushed function: InsertWatermarkInallfilesButton
        function InsertWatermarkInallfilesButtonPushed(app, event)
            global n;
            n=app.NumberofWatermarksSpinner.Value;
        
            
            for i=1:n
                     watermarkThis(i)
                     A(i,1)=i;
                 end
                 
                 filename = 'Database.xlsx';
                 
                 sheet = 1;
                 xlRange = 'B1';
                 xlswrite(filename,A,sheet,xlRange);
                 
                 app.Label.Text=char("Done");    
            
            
        end

        % Button pushed function: SearchWaterMarkButton
        function SearchWaterMarkButtonPushed(app, event)
            global n;
            a=extractThis(app.FileNameEditField.Value,n)
            
            [num,txt,raw] = xlsread('Database.xlsx')
            per=raw(a,1);
            whoLeaked='Person Who Leaked: ';
            person=strcat(whoLeaked,per);
            %person=int2str(a);
            app.PersonWhoLeakedLabel.Text=person;
            
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.InsertWatermarkPanel = uipanel(app.UIFigure);
            app.InsertWatermarkPanel.Title = 'Insert Watermark';
            app.InsertWatermarkInallfilesButton = uibutton(app.InsertWatermarkPanel, 'push');
            app.InsertWatermarkInallfilesButton.ButtonPushedFcn = createCallbackFcn(app, @InsertWatermarkInallfilesButtonPushed, true);
            app.InsertWatermarkInallfilesButton.Text = 'Insert Watermark In all files';
            app.Label = uilabel(app.InsertWatermarkPanel);
            app.Label.Text = '';
            app.NumberofWatermarksSpinnerLabel = uilabel(app.InsertWatermarkPanel);
            app.NumberofWatermarksSpinnerLabel.Text = 'Number of Watermarks: ';
            app.NumberofWatermarksSpinner = uispinner(app.InsertWatermarkPanel);
            app.ExtractWatermarkPanel = uipanel(app.UIFigure);
            app.ExtractWatermarkPanel.Title = 'Extract Watermark';
            app.PersonWhoLeakedLabel = uilabel(app.ExtractWatermarkPanel);
            app.PersonWhoLeakedLabel.Text = 'Person Who Leaked: ';
            app.SearchWaterMarkButton = uibutton(app.ExtractWatermarkPanel, 'push');
            app.SearchWaterMarkButton.ButtonPushedFcn = createCallbackFcn(app, @SearchWaterMarkButtonPushed, true);
            app.SearchWaterMarkButton.Text = 'Search WaterMark';
            app.FileNameLabel = uilabel(app.ExtractWatermarkPanel);
            app.FileNameLabel.Text = 'File Name:              ';
            app.FileNameEditField = uieditfield(app.ExtractWatermarkPanel, 'text');
        end
    end

    methods (Access = public)

        % Construct app
        function app = tutorialApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end